classdef FourierFilter03_21_23 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierDomainfilterUIFigure  matlab.ui.Figure
        InfoMenu                     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        ExtraMenu                    matlab.ui.container.Menu
        SmoothnessEditField          matlab.ui.control.NumericEditField
        SmoothnessEditFieldLabel     matlab.ui.control.Label
        ShoworiginalButton           matlab.ui.control.StateButton
        SavePanel                    matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        NosavelocationLabel          matlab.ui.control.Label
        ChangesavelocationButton     matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        Zoom                         matlab.ui.control.Slider
        zoomLabel                    matlab.ui.control.Label
        ClearButton                  matlab.ui.control.Button
        Spinner                      matlab.ui.control.Spinner
        OpenButton                   matlab.ui.control.Button
        YSlider                      matlab.ui.control.Slider
        XSlider                      matlab.ui.control.Slider
        TransformButton              matlab.ui.control.Button
        ShowButtonGroup              matlab.ui.container.ButtonGroup
        DataButton                   matlab.ui.control.ToggleButton
        FrqDomainButton              matlab.ui.control.ToggleButton
        UITable                      matlab.ui.control.Table
        SettoButton                  matlab.ui.control.Button
        ImportButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu2                 matlab.ui.container.ContextMenu
        SelectMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        x
        y
        T
        dx
        fhat
        freq
        inverse
        import
        zoomvalue
        panvalue
        oldf
        savepath
        saveconfirm

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.zoomvalue = 0;
            app.panvalue = 0;
            disableDefaultInteractivity(app.UIAxes)
            disableDefaultInteractivity(app.UIAxes)
            app.fhat = [];
            app.oldf = [];
            app.freq = [];
            app.inverse = [];
            app.import = false;
            app.saveconfirm = false;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            if ~isempty(app.inverse) && ~app.saveconfirm
                answer = questdlg('File is not saved! Would you like to save?', ...
                'Warning', ...
                'Yes','No','Cancel','Yes');
                % Handle response
                switch answer
                    case 'Yes'
                        SaveButtonPushed(app);
                    case 'No'
                        if ~isempty(app.UITable.Selection)
                            importfunc(app)
                        end
                    case 'Cancel'

                end
            else
                importfunc(app)
            end

            function importfunc(app)
                app.Zoom.Value = 1;
                app.Lamp.Color = [1,0,0];
                app.Label.Text = "Not Saved";
                app.ShoworiginalButton.Visible = false;
                app.ShoworiginalButton.Value = true;
                app.oldf = [];
                app.fhat = [];
                app.freq = [];
                app.dx = [];
                app.inverse = [];
                selectedvarname = app.UITable.Data{app.UITable.Selection(1),app.UITable.Selection(2)};
                app.UIAxes.Title.String = selectedvarname;
                app.ShowButtonGroup.SelectedObject = app.DataButton;
                app.import = true;
                app.x = app.T.(selectedvarname)(1:end,1);
                app.y = app.T.(selectedvarname)(1:end,2);
                app.dx = app.x(3)-app.x(2);
                plot(app.UIAxes,app.x,app.y,'k');
                app.XSlider.Value = 0.5;
                app.YSlider.Value = 1;
                XSliderValueChanged(app);
                YSliderValueChanged(app);
            end
        end

        % Button pushed function: SettoButton
        function SettoButtonPushed(app, event)
            selx = app.UIAxes.Children.BrushData;
            if app.ShowButtonGroup.SelectedObject == app.FrqDomainButton && ~isempty(selx)

                % prepare boundaries for filtering
                inds = find(selx);
                v = 10^(-app.SmoothnessEditField.Value);
                a = inds(1);
                d = app.freq(end-a+1);
                a = app.freq(a);
                b = inds(end);
                c = app.freq(end-b+1);
                b = app.freq(b);
                
%                 % mirror selection so it filters both sides
%                 vect = flip(selx);
%                 vect = vect(round(length(vect)/2):end);
%                 val = round(length(selx)/2);
%                 selx = selx(1:val);
%                 selx = [selx vect]';

                %filtering functions
                filtl = zeros(1,length(app.freq));
                filtr = zeros(1,length(app.freq));
                filt = zeros(1,length(app.freq));


                lim = app.Spinner.Value; % setvalue
                for i = 1:length(app.freq)
                    filtl(i) = 1-((1-lim)/2)*(erf((app.freq(i)-a)/v)-erf((app.freq(i)-b)/v));
                    filtr(i) = 1-((1-lim)/2)*(erf((app.freq(i)-c)/v)-erf((app.freq(i)-d)/v));
                    filt(i) = filtr(i)+filtl(i)-1;
                end


                app.oldf = app.fhat;
                
                app.fhat = app.fhat.*filt';

                % stuff for plotting
                filt = filt*app.UIAxes.YLim(2);

                plot(app.UIAxes,app.freq,app.oldf,'r');
                hold(app.UIAxes,"on")
                plot(app.UIAxes,app.freq,filt,"LineStyle","--","Color",'k');
                plot(app.UIAxes,app.freq,app.fhat,'k');
                hold(app.UIAxes,"off");
            else
                msgbox('Selection Empty','Attention!')
            end
        end

        % Menu selected function: SelectMenu
        function SelectMenuSelected(app, event)
              app.SelectMenu.Checked = ~app.SelectMenu.Checked;
              brush(app.UIAxes,app.SelectMenu.Checked);
        end

        % Selection changed function: ShowButtonGroup
        function ShowButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShowButtonGroup.SelectedObject;
            
            if selectedButton == app.FrqDomainButton && ~isempty(app.y)
                brush(app.UIAxes,app.SelectMenu.Checked);
                if isempty(app.fhat)
                    n = length(app.y);
                    app.fhat = fft(app.y,n);
                    app.dx = app.x(3)-app.x(2);
                    app.freq = 1/(app.dx*n)*(0:n-1);
                end
                if ~isempty(app.oldf)
                    plot(app.UIAxes,app.freq(1:floor(end/2)),app.oldf(1:floor(end/2)),'r','LineWidth',1);
                    hold(app.UIAxes,"on")
                    plot(app.UIAxes,app.freq(1:floor(end/2)),app.fhat(1:floor(end/2)),'-sk','LineWidth',1,'MarkerSize',2,...
                        'MarkerEdgeColor','red',...
                        'MarkerFaceColor',[1 .6 .6]);
                    hold(app.UIAxes,"off");
                else
                    plot(app.UIAxes,app.freq(1:floor(end/2)),app.fhat(1:floor(end/2)),'-sk','LineWidth',1,'MarkerSize',2,...
                        'MarkerEdgeColor','red',...
                        'MarkerFaceColor',[1 .6 .6]);
                end
                XSliderValueChanged(app);
                YSliderValueChanged(app);
                app.SettoButton.Enable = true;
                app.TransformButton.Enable = true;
                app.ShoworiginalButton.Visible = false;
            end
            if selectedButton == app.DataButton && ~isempty(app.y)
                brush(app.UIAxes,'off');
                if ~isempty(app.inverse)
                    plot(app.UIAxes,app.x,app.y,'r','LineWidth',1)
                    hold(app.UIAxes,"on")
                    plot(app.UIAxes,app.x,app.inverse,'k','LineWidth',1);
                    hold(app.UIAxes,"off")
                    app.SettoButton.Enable = false;
                else
                    app.SettoButton.Enable = false;
                    app.TransformButton.Enable = false;
                    plot(app.UIAxes,app.x,app.y,'k');
                end
                XSliderValueChanged(app);
                YSliderValueChanged(app);
            end
            
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            app.inverse = ifft(app.fhat,length(app.x));
            cla(app.UIAxes);
            app.ShoworiginalButton.Visible = true;
            if app.ShoworiginalButton.Value
                plot(app.UIAxes,app.x,app.y,'r')
            end
            hold(app.UIAxes,"on")
            plot(app.UIAxes,app.x,app.inverse,'k');
            hold(app.UIAxes,"off")
            xlim(app.UIAxes,[app.x(1) app.x(end)]);
            ylim(app.UIAxes,[min(app.y)*1.1 max(app.y)*1.1])
            app.ShowButtonGroup.SelectedObject = app.DataButton;
            brush(app.UIAxes,'off');
            app.SettoButton.Enable = false;
            app.TransformButton.Enable = false;
            app.Lamp.Color = [1,0,0];
            app.Label.Text = "Not saved";
            app.SaveButton.Enable = true;
            app.saveconfirm = false;
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            value = app.XSlider.Value;
            if app.ShowButtonGroup.SelectedObject == app.FrqDomainButton && app.import
%                 xlim(app.UIAxes,[app.freq(1) app.freq(floor(end/2))]);
                midpoint = (app.freq(floor(end/2)))*value;
                halfval = app.freq(floor(end/2))/2;
                zoom = app.Zoom.Value;
                xlim(app.UIAxes,[midpoint-halfval/zoom midpoint+halfval/zoom]);
            end
            if app.ShowButtonGroup.SelectedObject == app.DataButton && app.import
                midpoint = (app.x(end)-app.x(1))*value+app.x(1);
                halfval = (app.x(end)-app.x(1))/2;
                zoom = app.Zoom.Value;
                xlim(app.UIAxes,[midpoint-halfval/zoom midpoint+halfval/zoom]);
            end
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            value = app.YSlider.Value;
            if app.ShowButtonGroup.SelectedObject == app.FrqDomainButton && app.import
                midpoint = mean(real(app.fhat));
                halfval = abs(max(real(app.fhat))-midpoint);

                ylim(app.UIAxes,[midpoint-halfval/value midpoint+halfval/value]);
            end
            if app.ShowButtonGroup.SelectedObject == app.DataButton && app.import
                ylim(app.UIAxes,[min(app.y) max(app.y)/value]);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            if ~isempty(app.inverse) && ~isempty(app.savepath)
                savex = ["X";app.x];
                savey = ["Y";real(app.inverse)];
                M = [savex, savey];

                filename = app.UIAxes.Title.String(1:end);
                ind = strfind(filename,'.0');
                if ~isempty(ind)
                    newname = filename(1:ind(1)-1);
                    filename = strcat(app.savepath,newname,"_fft.txt");
                else
                    ind = strfind(filename,'.1');
                    if ~isempty(ind)
                        newname = filename(1:ind(1)-1);
                        filename = strcat(app.savepath,newname,"_1_fft.txt");
                    else 
                        filename = strcat(app.savepath,app.UIAxes.Title.String(1:end-4),"_fft.txt");
                    end
                end
                writematrix(M,filename,'Delimiter','space')
                app.Lamp.Color = [0,1,0];
                app.Label.Text = "Saved";
                app.saveconfirm = true;
            elseif ~isempty(app.inverse) && isempty(app.savepath)
                ChangesavelocationButtonPushed(app);
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename,path] = uigetfile("*.txt;*.DAT",'MultiSelect', 'on');
            if ~isnumeric(filename)
                filename = string(filename)';
                path = string(path);
                app.T = table();
                for i = 1:length(filename)
                    Ttemp = readtable(strcat(path,filename(i)));
                    if isnan(Ttemp{end,1}) || isnan(Ttemp{end,2})
                        Ttemp = Ttemp(1:end-1,:);
                    end
                    Ttemp.Properties.VariableNames = ["Var1","Var2"];
                    app.T.(filename(i)) = [Ttemp.Var1,Ttemp.Var2];
                end
                app.UITable.Data = table('Size',[length(filename),1],'VariableTypes',"string",'VariableNames',"File");
                app.UITable.Data.File = filename;
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
           
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if ~isempty(app.inverse) && ~app.saveconfirm
                answer = questdlg('File is not saved! Would you like to save?', ...
	            'Warning', ...
	            'Yes','No','Cancel','Yes');
                % Handle response
                switch answer
                    case 'Yes'
                        SaveButtonPushed(app);
                    case 'No'
                        clearfunc(app)
                    case 'Cancel'

                end
            else
                clearfunc(app)
            end

            function clearfunc(app)
                app.oldf = [];
                app.x = [];
                app.y = [];
                app.dx = [];
                app.inverse = [];
                cla(app.UIAxes);
                app.XSlider.Value = 0.5;
                app.YSlider.Value = 1;
                app.import = false;
                app.UIAxes.Title.String = "Title";
                app.ShowButtonGroup.SelectedObject = app.DataButton;
            end
        end

        % Button pushed function: ChangesavelocationButton
        function ChangesavelocationButtonPushed(app, event)
            path = uigetdir();
            if path ~= 0
                app.SaveButton.Enable = true;
                app.savepath = strcat(path,"/");
                app.NosavelocationLabel.Text = app.savepath;
            end
        end

        % Context menu opening function: ContextMenu2
        function ContextMenu2Opening(app, event)
            
        end

        % Value changed function: Zoom
        function ZoomValueChanged(app, event)
            XSliderValueChanged(app);
            
        end

        % Value changed function: ShoworiginalButton
        function ShoworiginalButtonValueChanged(app, event)
            TransformButtonPushed(app)
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;
            if isequal(key,'return')
                ImportButtonPushed(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourierDomainfilterUIFigure = uifigure('Visible', 'off');
            app.FourierDomainfilterUIFigure.Name = 'Fourier Domain filter';
            app.InfoMenu = uimenu(app.FourierDomainfilterUIFigure);
            app.InfoMenu.Text = 'Info';
            app.HelpMenu = uimenu(app.FourierDomainfilterUIFigure);
            app.HelpMenu.Text = 'Help';
            app.SettingsMenu = uimenu(app.FourierDomainfilterUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ExtraMenu = uimenu(app.FourierDomainfilterUIFigure);
            app.ExtraMenu.Text = 'Extra';
            app.UIAxes = uiaxes(app.FourierDomainfilterUIFigure);
            title(app.UIAxes, 'Title')
            app.ImportButton = uibutton(app.FourierDomainfilterUIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.SettoButton = uibutton(app.FourierDomainfilterUIFigure, 'push');
            app.SettoButton.ButtonPushedFcn = createCallbackFcn(app, @SettoButtonPushed, true);
            app.SettoButton.Enable = 'off';
            app.SettoButton.Text = 'Set to';
            app.UITable = uitable(app.FourierDomainfilterUIFigure);
            app.UITable.ColumnName = {'Files'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.Multiselect = 'off';
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.ShowButtonGroup = uibuttongroup(app.FourierDomainfilterUIFigure);
            app.ShowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowButtonGroupSelectionChanged, true);
            app.ShowButtonGroup.Title = 'Show';
            app.FrqDomainButton = uitogglebutton(app.ShowButtonGroup);
            app.FrqDomainButton.Text = 'Frq Domain';
            app.DataButton = uitogglebutton(app.ShowButtonGroup);
            app.DataButton.Text = 'Data';
            app.DataButton.Value = true;
            app.TransformButton = uibutton(app.FourierDomainfilterUIFigure, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Enable = 'off';
            app.TransformButton.Text = 'Transform';
            app.XSlider = uislider(app.FourierDomainfilterUIFigure);
            app.XSlider.Limits = [0 1];
            app.XSlider.MajorTicks = [];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.MinorTicks = [];
            app.XSlider.Value = 0.5;
            app.YSlider = uislider(app.FourierDomainfilterUIFigure);
            app.YSlider.Limits = [1 100];
            app.YSlider.MajorTicks = [];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.MinorTicks = [];
            app.YSlider.Value = 1;
            app.OpenButton = uibutton(app.FourierDomainfilterUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Spinner = uispinner(app.FourierDomainfilterUIFigure);
            app.Spinner.Step = 0.1;
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ClearButton = uibutton(app.FourierDomainfilterUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.zoomLabel = uilabel(app.FourierDomainfilterUIFigure);
            app.zoomLabel.Text = 'zoom';
            app.Zoom = uislider(app.FourierDomainfilterUIFigure);
            app.Zoom.Limits = [1 20];
            app.Zoom.MajorTicks = [1 5 10 15 20];
            app.Zoom.ValueChangedFcn = createCallbackFcn(app, @ZoomValueChanged, true);
            app.Zoom.Value = 1;
            app.SavePanel = uipanel(app.FourierDomainfilterUIFigure);
            app.SavePanel.Title = 'Save';
            app.SaveButton = uibutton(app.SavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ChangesavelocationButton = uibutton(app.SavePanel, 'push');
            app.ChangesavelocationButton.ButtonPushedFcn = createCallbackFcn(app, @ChangesavelocationButtonPushed, true);
            app.ChangesavelocationButton.Text = 'Change save location';
            app.NosavelocationLabel = uilabel(app.SavePanel);
            app.NosavelocationLabel.Text = 'No save location';
            app.Label = uilabel(app.SavePanel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.SavePanel);
            app.ShoworiginalButton = uibutton(app.FourierDomainfilterUIFigure, 'state');
            app.ShoworiginalButton.ValueChangedFcn = createCallbackFcn(app, @ShoworiginalButtonValueChanged, true);
            app.ShoworiginalButton.Visible = 'off';
            app.ShoworiginalButton.Text = 'Show original';
            app.ShoworiginalButton.Value = true;
            app.SmoothnessEditFieldLabel = uilabel(app.FourierDomainfilterUIFigure);
            app.SmoothnessEditFieldLabel.Text = 'Smoothness:';
            app.SmoothnessEditField = uieditfield(app.FourierDomainfilterUIFigure, 'numeric');
            app.SmoothnessEditField.Value = 3;
            app.ContextMenu2 = uicontextmenu(app.FourierDomainfilterUIFigure);
            app.ContextMenu2.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.SelectMenu = uimenu(app.ContextMenu2);
            app.SelectMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectMenuSelected, true);
            app.SelectMenu.Text = 'Select';
            % Assign app.ContextMenu2
            app.UIAxes.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.FourierDomainfilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierFilter03_21_23

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierDomainfilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierDomainfilterUIFigure)
        end
    end
end